classdef app11 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CH2Label_2                  matlab.ui.control.Label
        CH1Label_2                  matlab.ui.control.Label
        FrequencyHzTextArea_2       matlab.ui.control.TextArea
        FrequencyHzTextArea_2Label  matlab.ui.control.Label
        BPMTextArea_2               matlab.ui.control.TextArea
        BPMTextArea_2Label          matlab.ui.control.Label
        BPMTextArea                 matlab.ui.control.TextArea
        BPMTextAreaLabel            matlab.ui.control.Label
        FrequencyHzTextArea         matlab.ui.control.TextArea
        FrequencyHzTextAreaLabel    matlab.ui.control.Label
        DATATextArea                matlab.ui.control.TextArea
        DATATextAreaLabel           matlab.ui.control.Label
        Fs2DropDown                 matlab.ui.control.DropDown
        Fs2DropDownLabel            matlab.ui.control.Label
        Fs1DropDown                 matlab.ui.control.DropDown
        Fs1DropDownLabel            matlab.ui.control.Label
        HzLabel_2                   matlab.ui.control.Label
        HzLabel                     matlab.ui.control.Label
        STOPButton                  matlab.ui.control.Button
        CH2Label                    matlab.ui.control.Label
        CH1Label                    matlab.ui.control.Label
        ANALOGPIN2DropDown          matlab.ui.control.DropDown
        ANALOGPIN2DropDownLabel     matlab.ui.control.Label
        STARTButton                 matlab.ui.control.Button
        ANALOGPIN1DropDown          matlab.ui.control.DropDown
        ANALOGPIN1DropDownLabel     matlab.ui.control.Label
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description

            Serial = serialport("/dev/cu.wchusbserial14400", 115200); % connecting serial port
       
            fs_timer0 = 0;% sampling freq timer0
            fs_timer2 = 0;% sampling freq timer2


            hLine0;  % animated line for timer0
            hLine2;  % animated line for timer2

            hLineFreq0;  % animated line for timer0
            hLineFreq2;  % animated line for timer2

            current_index1 = 0;
            current_index2 = 0;

            freq_value0= 0.0; % stores current frequency value
            bpm0=0; % stores current bpm value

            freq_value2= 0.0; % stores current frequency value
            bpm2 =0; % stores current bpm value


            sensorValues0 = [];% we are collecting sensor data in this vector
            sensorValues2 = [];% we are collecting sensor data in this vector

            error_filter = 0; %this can be change according to fft result 

    end
    
    methods (Access = public)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Fs1DropDown
        function Fs1DropDownValueChanged(app, event)
            valueFs1 = app.Fs1DropDown.Value;
            %send a char to Serial port for every user input and adjust to
            %sampling frequency according to user choise in Matlab
            if valueFs1=="70"
                writeline(app.Serial,'1');
                app.fs_timer0 = 70;
            elseif valueFs1=="100"
                writeline(app.Serial,'2');
                app.fs_timer0 = 100;
            elseif valueFs1=="150"
                writeline(app.Serial,'3');
                app.fs_timer0 = 150;
            end
        end

        % Value changed function: Fs2DropDown
        function Fs2DropDownValueChanged(app, event)
            valueFs2 = app.Fs2DropDown.Value;
            
            if valueFs2=="70"
                writeline(app.Serial,'4');
                app.fs_timer2 = 70;
            elseif valueFs2=="100"
                writeline(app.Serial,'5');
                app.fs_timer2 = 100;
            elseif valueFs2=="150"
                writeline(app.Serial,'6');
                app.fs_timer2 = 150;
            end
        end

        % Value changed function: ANALOGPIN2DropDown
        function ANALOGPIN2DropDownValueChanged(app, event)
            analvalue2 = app.ANALOGPIN2DropDown.Value;

            if analvalue2=="A0"
                writeline(app.Serial, "g")
            elseif analvalue2=="A1"
                writeline(app.Serial, "h")
            elseif analvalue2=="A2"
                writeline(app.Serial, "i")
            elseif analvalue2=="A3"
                writeline(app.Serial, "k")
            elseif analvalue2=="A4"
                writeline(app.Serial, "l")
            elseif analvalue2=="A5"
                writeline(app.Serial, "m")
            end

            
        end

        % Value changed function: ANALOGPIN1DropDown
        function ANALOGPIN1DropDownValueChanged(app, event)
            analvalue1 = app.ANALOGPIN1DropDown.Value;

            if analvalue1=="A0"
                writeline(app.Serial, "a")
            elseif analvalue1=="A1"
                writeline(app.Serial, "b")
            elseif analvalue1=="A2"
                writeline(app.Serial, "c")
            elseif analvalue1=="A3"
                writeline(app.Serial, "d")
            elseif analvalue1=="A4"
                writeline(app.Serial, "e")
            elseif analvalue1=="A5"
                writeline(app.Serial, "f")
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)


            fileName1 = datestr(now, 'yyyymmdd_HHMMSS_1.txt');%set the name of  files with date and hour
            fileName2 = datestr(now, 'yyyymmdd_HHMMSS_2.txt');

            log1 = fopen(fileName1,'w'); %open files with write mode
            log2 = fopen(fileName2,'w');
            
            app.hLine0 = animatedline(app.UIAxes);% initialization of animated line for time domain plot
            app.hLine2 = animatedline(app.UIAxes_2);% initialization of animated line for freq domain plot
            app.hLineFreq0 = animatedline(app.UIAxes_3);% initialization of animated line for time domain plot
            app.hLineFreq2 = animatedline(app.UIAxes_4);% initialization of animated line for freq domain plot

            
            while true

              if app.Serial.NumBytesAvailable > 0 
                
                data = readline(app.Serial); % read the data from serial port
                app.DATATextArea.Value = string(data);
                
                datatotxt = data;
                data = strsplit(data, ',');

                timer_number = data{1};
                analog_data = str2double(data{2});


                if timer_number == '0'  % data coming from timer 0
                    fprintf(log1,datatotxt);
                    app.sensorValues0 = [app.sensorValues0  analog_data]; % add the data to sensor value vector
                    app.current_index1 = app.current_index1 + 1;
                    
                    addpoints(app.hLine0, (app.current_index1)/app.fs_timer0, analog_data);  % add the new sensor data to plot
                    %xlim(app.UIAxes,[app.current_index1/app.fs_timer0 - 0.5, app.current_index1/app.fs_timer0 - 0.5]); 
                    drawnow limitrate;

                    
                    fftLen = 256;%fft lenght
                    if app.current_index1>fftLen+1   % if data collected

                        % Performing Fourier transform
                        dataToFFT0 = detrend(app.sensorValues0(end-fftLen+1:end)); % fft for last 256 data.
                        fftResult0 = fft(dataToFFT0);
                        fftResult0 = fftshift(abs(fftResult0));  % Shift zero frequency component to the center
                        fftFrequency0 = linspace(-app.fs_timer0/2, app.fs_timer0/2, fftLen);

                        % Update the frequency-domain plot
                        clearpoints(app.hLineFreq0);
                        addpoints(app.hLineFreq0, fftFrequency0, fftResult0);
                        xlim(app.UIAxes_3,[-app.fs_timer0/2, app.fs_timer0/2]);
                        drawnow limitrate;

                        indice0 = find(abs(fftResult0)==max(abs(fftResult0)));   %finding max freq value in freq domain
                        if length(indice0)>1
                            app.freq_value0 = fftFrequency0(indice0(end))- app.error_filter;  % obtaining frequency
                            app.bpm0  = round(app.freq_value0*60); % obtainin bpm
                            app.FrequencyHzTextArea.Value = string(app.freq_value0); %print freq to text area
                            app.BPMTextArea.Value = string(app.bpm0); %print bpm to text area

                        end

                    end

                elseif timer_number == '2' % data coming from timer 2

                    fprintf(log2,datatotxt);
                    app.sensorValues2 = [app.sensorValues2  analog_data]; % add the data to sensor value vector
                    app.current_index2 = app.current_index2 + 1;
                    addpoints(app.hLine2, (app.current_index2)/app.fs_timer2, analog_data);  % add the new sensor data to plot
                    %xlim(app.UIAxes_2,[app.current_index2/app.fs_timer2 - 0.5, app.current_index2/app.fs_timer2 - 0.5]); 
                    drawnow limitrate;

                    fftLen = 256;%fft lenght
                    if app.current_index2>fftLen+1   % if data collected

                        % Performing Fourier transform
                        dataToFFT2 = detrend(app.sensorValues2(end-fftLen+1:end)); % fft for last 256 data.
                        fftResult2 = fft(dataToFFT2);
                        fftResult2 = fftshift(abs(fftResult2));  % Shift zero frequency component to the center
                        fftFrequency2 = linspace(-app.fs_timer2/2, app.fs_timer2/2, fftLen);

                        % Update the frequency-domain plot
                        clearpoints(app.hLineFreq2);
                        addpoints(app.hLineFreq2, fftFrequency2, fftResult2);
                        xlim(app.UIAxes_4,[-app.fs_timer2/2, app.fs_timer2/2]);
                        drawnow limitrate;

                        indice2 = find(abs(fftResult2)==max(abs(fftResult2)));   %finding max freq value in freq domain
                        if length(indice2)>1
                            app.freq_value2 = fftFrequency2(indice2(end))- app.error_filter;  % obtaining frequency
                            app.bpm2  = round(app.freq_value2*60); % obtainin bpm
                            app.FrequencyHzTextArea_2.Value = string(app.freq_value2); %print freq to text area
                            app.BPMTextArea_2.Value = string(app.bpm2); %print bpm to text area

                        end

                    end

                end
                
              end
              
            end
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            while true
                pause(0.1); %stopped to communication

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Channel1 - Time Domain')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Channel 2 - Time Domain')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Channel1 - FFT ')
            xlabel(app.UIAxes_3, 'Frequency')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Channel2 - FFT ')
            xlabel(app.UIAxes_4, 'Frequency')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.ANALOGPIN1DropDownLabel = uilabel(app.UIFigure);
            app.ANALOGPIN1DropDownLabel.Text = 'ANALOG PIN 1';
            app.ANALOGPIN1DropDown = uidropdown(app.UIFigure);
            app.ANALOGPIN1DropDown.Items = {'', 'A0', 'A1', 'A2', 'A3', 'A4', 'A5'};
            app.ANALOGPIN1DropDown.ValueChangedFcn = createCallbackFcn(app, @ANALOGPIN1DropDownValueChanged, true);
            app.ANALOGPIN1DropDown.Value = '';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.ANALOGPIN2DropDownLabel = uilabel(app.UIFigure);
            app.ANALOGPIN2DropDownLabel.Text = 'ANALOG PIN 2';
            app.ANALOGPIN2DropDown = uidropdown(app.UIFigure);
            app.ANALOGPIN2DropDown.Items = {'', 'A0', 'A1', 'A2', 'A3', 'A4', 'A5'};
            app.ANALOGPIN2DropDown.ValueChangedFcn = createCallbackFcn(app, @ANALOGPIN2DropDownValueChanged, true);
            app.ANALOGPIN2DropDown.Value = '';
            app.CH1Label = uilabel(app.UIFigure);
            app.CH1Label.Text = 'CH1';
            app.CH2Label = uilabel(app.UIFigure);
            app.CH2Label.Text = 'CH2';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.HzLabel = uilabel(app.UIFigure);
            app.HzLabel.Text = 'Hz';
            app.HzLabel_2 = uilabel(app.UIFigure);
            app.HzLabel_2.Text = 'Hz';
            app.Fs1DropDownLabel = uilabel(app.UIFigure);
            app.Fs1DropDownLabel.Text = 'Fs1';
            app.Fs1DropDown = uidropdown(app.UIFigure);
            app.Fs1DropDown.Items = {'', '70', '100', '150'};
            app.Fs1DropDown.ValueChangedFcn = createCallbackFcn(app, @Fs1DropDownValueChanged, true);
            app.Fs1DropDown.Value = '';
            app.Fs2DropDownLabel = uilabel(app.UIFigure);
            app.Fs2DropDownLabel.Text = 'Fs2';
            app.Fs2DropDown = uidropdown(app.UIFigure);
            app.Fs2DropDown.Items = {'', '70', '100', '150'};
            app.Fs2DropDown.ValueChangedFcn = createCallbackFcn(app, @Fs2DropDownValueChanged, true);
            app.Fs2DropDown.Value = '';
            app.DATATextAreaLabel = uilabel(app.UIFigure);
            app.DATATextAreaLabel.Text = 'DATA';
            app.DATATextArea = uitextarea(app.UIFigure);
            app.FrequencyHzTextAreaLabel = uilabel(app.UIFigure);
            app.FrequencyHzTextAreaLabel.Text = 'Frequency(Hz):';
            app.FrequencyHzTextArea = uitextarea(app.UIFigure);
            app.BPMTextAreaLabel = uilabel(app.UIFigure);
            app.BPMTextAreaLabel.Text = 'BPM';
            app.BPMTextArea = uitextarea(app.UIFigure);
            app.BPMTextArea_2Label = uilabel(app.UIFigure);
            app.BPMTextArea_2Label.Text = 'BPM';
            app.BPMTextArea_2 = uitextarea(app.UIFigure);
            app.FrequencyHzTextArea_2Label = uilabel(app.UIFigure);
            app.FrequencyHzTextArea_2Label.Text = 'Frequency(Hz)';
            app.FrequencyHzTextArea_2 = uitextarea(app.UIFigure);
            app.CH1Label_2 = uilabel(app.UIFigure);
            app.CH1Label_2.Text = 'CH1';
            app.CH2Label_2 = uilabel(app.UIFigure);
            app.CH2Label_2.Text = 'CH2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app11

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end